classdef DICAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProjectMenu                   matlab.ui.container.Menu
        ProjectNewMenu                matlab.ui.container.Menu
        ProjectOpenMenu               matlab.ui.container.Menu
        ProjectSaveMenu               matlab.ui.container.Menu
        ProjectClearMenu              matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        DICMenu                       matlab.ui.container.Menu
        DICPreprocessCalculationMethodMenu  matlab.ui.container.Menu
        DICFileFormatMenu             matlab.ui.container.Menu
        DICColormapMenu               matlab.ui.container.Menu
        CLimCoeffMenu                 matlab.ui.container.Menu
        DICShowAxesMenu               matlab.ui.container.Menu
        ValueRangesMenu               matlab.ui.container.Menu
        EBSDMenu                      matlab.ui.container.Menu
        EBSDPolygonizeMenu            matlab.ui.container.Menu
        GBSmoothDegreeMenu            matlab.ui.container.Menu
        MinimumGrainSizeMenu          matlab.ui.container.Menu
        ParallelComputingMenu         matlab.ui.container.Menu
        EBSDShowAxesMenu              matlab.ui.container.Menu
        PlotMenu                      matlab.ui.container.Menu
        MaxResolutionMenu             matlab.ui.container.Menu
        GBStyleMenu                   matlab.ui.container.Menu
        GrainLabelsStyleMenu          matlab.ui.container.Menu
        OverlayOpacityMenu            matlab.ui.container.Menu
        LanguageMenu                  matlab.ui.container.Menu
        ChineseMenu                   matlab.ui.container.Menu
        EnglishMenu                   matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        ExportImagesMenu              matlab.ui.container.Menu
        ExportCurvesMenu              matlab.ui.container.Menu
        ExportDataMenu                matlab.ui.container.Menu
        ExportEBSDDataangMenu         matlab.ui.container.Menu
        ExportABAQUSinputfileinpMenu  matlab.ui.container.Menu
        ExtensionsMenu                matlab.ui.container.Menu
        DebugMenu                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayoutLeft                matlab.ui.container.GridLayout
        GridLayout29                  matlab.ui.container.GridLayout
        GridLayout30_2                matlab.ui.container.GridLayout
        StageDropDown                 matlab.ui.control.DropDown
        StageDropDownLabel            matlab.ui.control.Label
        GridLayout30                  matlab.ui.container.GridLayout
        EBSDDropDown                  matlab.ui.control.DropDown
        EBSDDropDownLabel             matlab.ui.control.Label
        OverlayPolygons               matlab.ui.control.StateButton
        OverlayDICButton              matlab.ui.control.StateButton
        MonitorButton                 matlab.ui.control.StateButton
        StatisticButton               matlab.ui.control.Button
        StyleButton                   matlab.ui.control.StateButton
        Tree2                         matlab.ui.container.CheckBoxTree
        Tree                          matlab.ui.container.Tree
        GridLayout12                  matlab.ui.container.GridLayout
        ImageIcon                     matlab.ui.control.Image
        AppNameLabel                  matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        ImagesTab                     matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        UIAxesImages                  matlab.ui.control.UIAxes
        Images2Tab                    matlab.ui.container.Tab
        GridLayout6_2                 matlab.ui.container.GridLayout
        UIAxesImages2                 matlab.ui.control.UIAxes
        ValueTab                      matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        FrequencyTab                  matlab.ui.container.Tab
        GridLayout6_3                 matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        GridLayoutRight               matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        ButtonFold                    matlab.ui.control.Button
        GridLayout10                  matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        TabGroup2                     matlab.ui.container.TabGroup
        GrainsTab                     matlab.ui.container.Tab
        GridLayout20                  matlab.ui.container.GridLayout
        GridLayout22                  matlab.ui.container.GridLayout
        DICPanel                      matlab.ui.container.Panel
        GridLayout26                  matlab.ui.container.GridLayout
        DICTree                       matlab.ui.container.Tree
        GridLayout27                  matlab.ui.container.GridLayout
        ShowButton                    matlab.ui.control.StateButton
        GrainSelectionPanel           matlab.ui.container.Panel
        GridLayout24                  matlab.ui.container.GridLayout
        GridLayout28                  matlab.ui.container.GridLayout
        GrainSelectionButton          matlab.ui.control.Button
        GrainSelectionTree            matlab.ui.container.CheckBoxTree
        FrontierDevSpinner            matlab.ui.control.Spinner
        IntrinsicCheckBox             matlab.ui.control.CheckBox
        FrontierCheckBox              matlab.ui.control.CheckBox
        InteriorCheckBox              matlab.ui.control.CheckBox
        NeighboursCheckBox            matlab.ui.control.CheckBox
        LabelsCheckBox                matlab.ui.control.CheckBox
        BoundaryPanel                 matlab.ui.container.Panel
        GridLayout25                  matlab.ui.container.GridLayout
        GBsDropDown                   matlab.ui.control.DropDown
        GBsSmoothButton               matlab.ui.control.StateButton
        GBsCheckBox                   matlab.ui.control.CheckBox
        GridLayout21                  matlab.ui.container.GridLayout
        AdjustLabel                   matlab.ui.control.Label
        PolygonizeLabel               matlab.ui.control.Label
        ImageAdjust                   matlab.ui.control.Image
        ImagePolygonize               matlab.ui.control.Image
        CurvesTab                     matlab.ui.container.Tab
        GridLayout31                  matlab.ui.container.GridLayout
        StatisticalIntervalsPanel     matlab.ui.container.Panel
        GridLayout35                  matlab.ui.container.GridLayout
        StatisticalIntervalsNumberSpinner  matlab.ui.control.Spinner
        StatisticalIntervalsNumberLabel  matlab.ui.control.Label
        StatisticalIntervalsLimitsLabel  matlab.ui.control.Label
        StatisticalIntervalsMaxEditField  matlab.ui.control.NumericEditField
        StatisticalIntervalsMinEditField  matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        AxisSettingsPanel             matlab.ui.container.Panel
        GridLayout34                  matlab.ui.container.GridLayout
        AxisSettingsAutoButton        matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        AxisSettingsYMaxEditField     matlab.ui.control.NumericEditField
        AxisSettingsYMinEditField     matlab.ui.control.NumericEditField
        AxisSettingsXMaxEditField     matlab.ui.control.NumericEditField
        AxisSettingsXMinEditField     matlab.ui.control.NumericEditField
        AxisSettingsYLabel            matlab.ui.control.Label
        AxisSettingsXLabel            matlab.ui.control.Label
        LineStylePanel                matlab.ui.container.Panel
        GridLayout32                  matlab.ui.container.GridLayout
        GridLayout33                  matlab.ui.container.GridLayout
        LinePropertiesRandomColorButton  matlab.ui.control.Button
        LinePropertiesColorButton     matlab.ui.control.Button
        LineStyleColorLabel           matlab.ui.control.Label
        LineStyleWidthSpinner         matlab.ui.control.Spinner
        LineStyleWidthLabel           matlab.ui.control.Label
        LineStyleStyleDropDown        matlab.ui.control.DropDown
        LineStyleStyleLabel           matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        UIAxesImages3                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        ROOTPATH

        OldPath

        Default
        TreeContextMenu
        Tree2ContextMenu
        UIAxesContextMenu
        
        CurrentProjectSelection
        % CurrentDICSelection
        CurrentEBSDSelection

        %%%%% - getConstants.m ↓↓↓
        UIControlDisplayNames
        UIControlDisplayNamesIndex
        OtherDisplayNames
        TreeNodeTypes
        TextAreaStrings
        ConstantValues
        TreeContextMenuTypes
        %%%%% - getConstants.m ↑↑↑
        
        DICFileFormats          % - getDICFileFormats.m -
        DICPreprocessMethods    % - getDICPreprocessMethods.m -
        DICCalculateMethods     % - getDICCalculateMethods.m -
        EBSDPreprocessMethods   % - getEBSDPreprocessMethods.m -
        Extensions              % - getExtensionInfos.m -

        Serial = 0
        Projects = []

        CurrentImage    = gobjects(1)
        DICColorbar
        CurrentImage2   = gobjects(1)
        OverlayImage    = gobjects(1)
        OverlayImage2   = gobjects(1)
        GBsPlot         = gobjects(1)
        PBsPlot         = gobjects(1)
        GrainLabels     = gobjects(1)
        
        DisplayIndependentFigure
        DisplayIndependentFlag = false

        GBStyleUIFigure
        GrainLabelsStyleUIFigure
        StyleUIs = struct( 'UIFigure', gobjects(1) )
        StatisticUIFigure
        
        UIAxesValue
        UIAxesFrequency

        MonitorUIFigure
        MonitorTable
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            %
            app.OldPath = path;
            temp1 = mfilename('fullpath') ; temp2 = strfind(temp1,'\');
            app.ROOTPATH = temp1(1:temp2(end));
            cd(app.ROOTPATH);
            addpath( app.ROOTPATH, ...
                genpath('sources'),genpath('app_funs'), ...
                genpath('cal_funs'), genpath('USER_funs'), ...
                genpath('SlipAnalysis'), genpath('AuxiliaryScripts') );
            
            SavedDefault = getDefault();
            if isfile('Default.mat')
                SavedDefault = updateDefault('Default.mat',SavedDefault);
            end
            app.Default = SavedDefault;
            save('Default.mat', 'SavedDefault')

            getConstants(app)
            setCallBacks(app)
            
            getDICFileFormats(app)
            getDICPreprocessMethods(app)
            getDICCalculateMethods(app)
            getEBSDPreprocessMethods(app)
            getExtensionInfos(app)

            getUserMaterialFormats(app)

            app.ImageIcon.ImageSource = app.ConstantValues.IconSource;
            app.UIFigure.Icon = app.ConstantValues.IconSource;
            app.UIFigure.Position = app.Default.UIFigureInitialSize;
            
            setOthers(app)
            setUIControlDisplayNames(app)

            % set UIs
            UIFigureSizeChangedFcn(app.UIFigure,[],app)
            app.AppNameLabel.FontSize = app.Default.AppNameLabelFontSize;

            app.UIFigure.Color = [1 1 1];

            dbstop in DICAnalyzer 300
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            msg = app.OtherDisplayNames( app.Default.LanguageSelection ). ...
                UIFigureCloseRequestuiconfirm_msg;
            title = app.OtherDisplayNames( app.Default.LanguageSelection ). ...
                UIFigureCloseRequestuiconfirm_title;
            selection = uiconfirm( app.UIFigure, msg, title, ...
                'Options', ...
                {app.OtherDisplayNames( app.Default.LanguageSelection ).uiopt_yes, ...
                 app.OtherDisplayNames( app.Default.LanguageSelection ).uiopt_no }, ...
                'DefaultOption',2);
            if strcmp( selection, ...
                    app.OtherDisplayNames( app.Default.LanguageSelection ).uiopt_no )
                return
            end

            arrayfun( @ delete, app.Projects )
            
            setDefaultuponClosing( app )

            SavedDefault = app.Default;
            save([app.ROOTPATH,'Default.mat'],'SavedDefault')
            path(app.OldPath)

            if app.DisplayIndependentFlag
                delete( app.DisplayIndependentFigure )
            end
            delete( app.GBStyleUIFigure )
            delete( app.GrainLabelsStyleUIFigure )
            delete( app.StyleUIs.UIFigure )
            delete( app.MonitorUIFigure )
            structfun( @(ext) delete(ext.gobjs), app.Extensions )
            delete(app)
          
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            objs = app.Projects;
            pause
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {479, 479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x', 319};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ProjectNewMenu = uimenu(app.ProjectMenu);
            app.ProjectNewMenu.Text = 'New';
            app.ProjectOpenMenu = uimenu(app.ProjectMenu);
            app.ProjectOpenMenu.Text = 'Open';
            app.ProjectSaveMenu = uimenu(app.ProjectMenu);
            app.ProjectSaveMenu.Text = 'Save';
            app.ProjectClearMenu = uimenu(app.ProjectMenu);
            app.ProjectClearMenu.Text = 'Clear';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.DICMenu = uimenu(app.OptionsMenu);
            app.DICMenu.Text = 'DIC';
            app.DICPreprocessCalculationMethodMenu = uimenu(app.DICMenu);
            app.DICPreprocessCalculationMethodMenu.Text = 'Strain Calculation Method';
            app.DICFileFormatMenu = uimenu(app.DICMenu);
            app.DICFileFormatMenu.Text = 'File Format';
            app.DICColormapMenu = uimenu(app.DICMenu);
            app.DICColormapMenu.Text = 'Colormap';
            app.CLimCoeffMenu = uimenu(app.DICMenu);
            app.CLimCoeffMenu.Text = 'Color Limits Coefficient';
            app.DICShowAxesMenu = uimenu(app.DICMenu);
            app.DICShowAxesMenu.Text = 'Show Axes';
            app.DICShowAxesMenu.Tag = 'DIC';
            app.ValueRangesMenu = uimenu(app.DICMenu);
            app.ValueRangesMenu.Text = 'Value Ranges';
            app.EBSDMenu = uimenu(app.OptionsMenu);
            app.EBSDMenu.Text = 'EBSD';
            app.EBSDPolygonizeMenu = uimenu(app.EBSDMenu);
            app.EBSDPolygonizeMenu.Text = 'Polygonize';
            app.GBSmoothDegreeMenu = uimenu(app.EBSDPolygonizeMenu);
            app.GBSmoothDegreeMenu.Text = 'GB Smooth Degree';
            app.MinimumGrainSizeMenu = uimenu(app.EBSDPolygonizeMenu);
            app.MinimumGrainSizeMenu.Text = 'Minimum Grain Size';
            app.ParallelComputingMenu = uimenu(app.EBSDPolygonizeMenu);
            app.ParallelComputingMenu.Text = 'Parallel Computing';
            app.EBSDShowAxesMenu = uimenu(app.EBSDMenu);
            app.EBSDShowAxesMenu.Text = 'Show Axes';
            app.EBSDShowAxesMenu.Tag = 'EBSD';
            app.PlotMenu = uimenu(app.OptionsMenu);
            app.PlotMenu.Text = 'Plot';
            app.MaxResolutionMenu = uimenu(app.PlotMenu);
            app.MaxResolutionMenu.Text = 'Max Resolution';
            app.GBStyleMenu = uimenu(app.PlotMenu);
            app.GBStyleMenu.Text = 'GB';
            app.GrainLabelsStyleMenu = uimenu(app.PlotMenu);
            app.GrainLabelsStyleMenu.Text = 'Grain Labels';
            app.OverlayOpacityMenu = uimenu(app.PlotMenu);
            app.OverlayOpacityMenu.Text = 'Overlay Opacity';
            app.LanguageMenu = uimenu(app.OptionsMenu);
            app.LanguageMenu.Text = 'Language';
            app.ChineseMenu = uimenu(app.LanguageMenu);
            app.ChineseMenu.Text = 'Chinese';
            app.EnglishMenu = uimenu(app.LanguageMenu);
            app.EnglishMenu.Text = 'English';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportImagesMenu = uimenu(app.ExportMenu);
            app.ExportImagesMenu.Text = 'Images';
            app.ExportCurvesMenu = uimenu(app.ExportMenu);
            app.ExportCurvesMenu.Text = 'Curves';
            app.ExportDataMenu = uimenu(app.ExportMenu);
            app.ExportDataMenu.Text = 'Data';
            app.ExportEBSDDataangMenu = uimenu(app.ExportMenu);
            app.ExportEBSDDataangMenu.Text = 'EBSD Data (.ang)';
            app.ExportABAQUSinputfileinpMenu = uimenu(app.ExportMenu);
            app.ExportABAQUSinputfileinpMenu.Text = 'ABAQUS input file (.inp)';
            app.ExtensionsMenu = uimenu(app.UIFigure);
            app.ExtensionsMenu.Text = 'Extensions';
            app.DebugMenu = uimenu(app.UIFigure);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.GridLayout12 = uigridlayout(app.GridLayoutLeft);
            app.AppNameLabel = uilabel(app.GridLayout12);
            app.AppNameLabel.Text = 'DIC Analyzer';
            app.ImageIcon = uiimage(app.GridLayout12);
            app.Tree = uitree(app.GridLayoutLeft);
            app.Tree.Multiselect = 'on';
            app.Tree2 = uitree(app.GridLayoutLeft, 'checkbox');
            app.GridLayout29 = uigridlayout(app.GridLayoutLeft);
            app.StyleButton = uibutton(app.GridLayout29, 'state');
            app.StyleButton.Text = '';
            app.StatisticButton = uibutton(app.GridLayout29, 'push');
            app.StatisticButton.Text = '';
            app.MonitorButton = uibutton(app.GridLayout29, 'state');
            app.MonitorButton.Text = '';
            app.OverlayDICButton = uibutton(app.GridLayout29, 'state');
            app.OverlayDICButton.Text = '';
            app.OverlayPolygons = uibutton(app.GridLayout29, 'state');
            app.OverlayPolygons.Text = '';
            app.GridLayout30 = uigridlayout(app.GridLayout29);
            app.EBSDDropDownLabel = uilabel(app.GridLayout30);
            app.EBSDDropDownLabel.Text = 'IPF';
            app.EBSDDropDown = uidropdown(app.GridLayout30);
            app.EBSDDropDown.Items = {''};
            app.EBSDDropDown.Value = '';
            app.GridLayout30_2 = uigridlayout(app.GridLayout29);
            app.StageDropDownLabel = uilabel(app.GridLayout30_2);
            app.StageDropDownLabel.Text = 'Stage';
            app.StageDropDown = uidropdown(app.GridLayout30_2);
            app.StageDropDown.Items = {''};
            app.StageDropDown.Value = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.CenterPanel);
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImagesTab = uitab(app.TabGroup);
            app.ImagesTab.Title = 'Images';
            app.ImagesTab.Tag = '1';
            app.GridLayout5 = uigridlayout(app.ImagesTab);
            app.UIAxesImages = uiaxes(app.GridLayout5);
            app.Images2Tab = uitab(app.TabGroup);
            app.Images2Tab.Title = 'Images2';
            app.Images2Tab.Tag = '2';
            app.GridLayout6_2 = uigridlayout(app.Images2Tab);
            app.UIAxesImages2 = uiaxes(app.GridLayout6_2);
            app.ValueTab = uitab(app.TabGroup);
            app.ValueTab.Title = 'Value';
            app.ValueTab.Tag = '3';
            app.GridLayout6 = uigridlayout(app.ValueTab);
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.FrequencyTab.Tag = '4';
            app.GridLayout6_3 = uigridlayout(app.FrequencyTab);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.UIAxesImages3 = uiaxes(app.GridLayoutRight);
            zlabel(app.UIAxesImages3, 'Z')
            app.GridLayout9 = uigridlayout(app.GridLayoutRight);
            app.TextArea = uitextarea(app.GridLayout9);
            app.TextArea.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.GrainsTab = uitab(app.TabGroup2);
            app.GrainsTab.Title = 'Grains';
            app.GridLayout20 = uigridlayout(app.GrainsTab);
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.ImagePolygonize = uiimage(app.GridLayout21);
            app.ImageAdjust = uiimage(app.GridLayout21);
            app.PolygonizeLabel = uilabel(app.GridLayout21);
            app.PolygonizeLabel.Text = 'Polygonize';
            app.AdjustLabel = uilabel(app.GridLayout21);
            app.AdjustLabel.Text = 'Adjust';
            app.GridLayout22 = uigridlayout(app.GridLayout20);
            app.BoundaryPanel = uipanel(app.GridLayout22);
            app.BoundaryPanel.Title = 'Boundary';
            app.GridLayout25 = uigridlayout(app.BoundaryPanel);
            app.GBsCheckBox = uicheckbox(app.GridLayout25);
            app.GBsCheckBox.Text = 'GBs';
            app.GBsSmoothButton = uibutton(app.GridLayout25, 'state');
            app.GBsSmoothButton.Text = 'Smooth';
            app.GBsDropDown = uidropdown(app.GridLayout25);
            app.GBsDropDown.Items = {};
            app.GBsDropDown.Value = {};
            app.GrainSelectionPanel = uipanel(app.GridLayout22);
            app.GrainSelectionPanel.Title = 'Grain Selection';
            app.GridLayout24 = uigridlayout(app.GrainSelectionPanel);
            app.LabelsCheckBox = uicheckbox(app.GridLayout24);
            app.LabelsCheckBox.Text = 'Labels';
            app.NeighboursCheckBox = uicheckbox(app.GridLayout24);
            app.NeighboursCheckBox.Text = 'Neighbours';
            app.InteriorCheckBox = uicheckbox(app.GridLayout24);
            app.InteriorCheckBox.Text = 'Interior';
            app.FrontierCheckBox = uicheckbox(app.GridLayout24);
            app.FrontierCheckBox.Text = 'Frontier';
            app.IntrinsicCheckBox = uicheckbox(app.GridLayout24);
            app.IntrinsicCheckBox.Text = 'Intrinsic';
            app.FrontierDevSpinner = uispinner(app.GridLayout24);
            app.GridLayout28 = uigridlayout(app.GridLayout24);
            app.GrainSelectionTree = uitree(app.GridLayout28, 'checkbox');
            app.GrainSelectionButton = uibutton(app.GridLayout28, 'push');
            app.GrainSelectionButton.Text = '';
            app.DICPanel = uipanel(app.GridLayout22);
            app.DICPanel.Title = 'DIC';
            app.GridLayout26 = uigridlayout(app.DICPanel);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.ShowButton = uibutton(app.GridLayout27, 'state');
            app.ShowButton.Text = 'Show';
            app.DICTree = uitree(app.GridLayout26);
            app.CurvesTab = uitab(app.TabGroup2);
            app.CurvesTab.Title = 'Curves';
            app.GridLayout31 = uigridlayout(app.CurvesTab);
            app.LineStylePanel = uipanel(app.GridLayout31);
            app.LineStylePanel.Title = 'Line Style';
            app.GridLayout32 = uigridlayout(app.LineStylePanel);
            app.LineStyleStyleLabel = uilabel(app.GridLayout32);
            app.LineStyleStyleLabel.Text = 'Style';
            app.LineStyleStyleDropDown = uidropdown(app.GridLayout32);
            app.LineStyleWidthLabel = uilabel(app.GridLayout32);
            app.LineStyleWidthLabel.Text = 'Width';
            app.LineStyleWidthSpinner = uispinner(app.GridLayout32);
            app.LineStyleWidthSpinner.Step = 0.5;
            app.LineStyleColorLabel = uilabel(app.GridLayout32);
            app.LineStyleColorLabel.Text = 'Color';
            app.GridLayout33 = uigridlayout(app.GridLayout32);
            app.LinePropertiesColorButton = uibutton(app.GridLayout33, 'push');
            app.LinePropertiesColorButton.Text = '';
            app.LinePropertiesRandomColorButton = uibutton(app.GridLayout33, 'push');
            app.LinePropertiesRandomColorButton.Text = '';
            app.AxisSettingsPanel = uipanel(app.GridLayout31);
            app.AxisSettingsPanel.Title = 'Axis Settings';
            app.GridLayout34 = uigridlayout(app.AxisSettingsPanel);
            app.AxisSettingsXLabel = uilabel(app.GridLayout34);
            app.AxisSettingsXLabel.Text = 'X';
            app.AxisSettingsYLabel = uilabel(app.GridLayout34);
            app.AxisSettingsYLabel.Text = 'Y';
            app.AxisSettingsXMinEditField = uieditfield(app.GridLayout34, 'numeric');
            app.AxisSettingsXMaxEditField = uieditfield(app.GridLayout34, 'numeric');
            app.AxisSettingsYMinEditField = uieditfield(app.GridLayout34, 'numeric');
            app.AxisSettingsYMaxEditField = uieditfield(app.GridLayout34, 'numeric');
            app.Label = uilabel(app.GridLayout34);
            app.Label.Text = '~';
            app.Label_2 = uilabel(app.GridLayout34);
            app.Label_2.Text = '~';
            app.AxisSettingsAutoButton = uibutton(app.GridLayout34, 'push');
            app.AxisSettingsAutoButton.Text = '';
            app.StatisticalIntervalsPanel = uipanel(app.GridLayout31);
            app.StatisticalIntervalsPanel.Title = 'Statistical Intervals';
            app.GridLayout35 = uigridlayout(app.StatisticalIntervalsPanel);
            app.Label_3 = uilabel(app.GridLayout35);
            app.Label_3.Text = '~';
            app.StatisticalIntervalsMinEditField = uieditfield(app.GridLayout35, 'numeric');
            app.StatisticalIntervalsMaxEditField = uieditfield(app.GridLayout35, 'numeric');
            app.StatisticalIntervalsLimitsLabel = uilabel(app.GridLayout35);
            app.StatisticalIntervalsLimitsLabel.Text = 'Limits';
            app.StatisticalIntervalsNumberLabel = uilabel(app.GridLayout35);
            app.StatisticalIntervalsNumberLabel.Text = 'Number';
            app.StatisticalIntervalsNumberSpinner = uispinner(app.GridLayout35);
            app.GridLayout10 = uigridlayout(app.GridLayoutRight);
            app.GridLayout11 = uigridlayout(app.GridLayoutRight);
            app.ButtonFold = uibutton(app.GridLayout11, 'push');
            app.ButtonFold.Icon = fullfile(pathToMLAPP, 'sources', 'icons', 'down.png');
            app.ButtonFold.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DICAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end